classdef SVGExport < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        KpeltolsaypxEditField       matlab.ui.control.NumericEditField
        KpeltolsaypxEditFieldLabel  matlab.ui.control.Label
        KpmretepxEditField          matlab.ui.control.NumericEditField
        KpmretepxEditFieldLabel     matlab.ui.control.Label
        KpeltolsaxpxEditField       matlab.ui.control.NumericEditField
        KpeltolsaxpxEditFieldLabel  matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        FjlnvEditField              matlab.ui.control.EditField
        FjlnvEditFieldLabel         matlab.ui.control.Label
        TallzsButton                matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        SzaggatottvonalakCheckBox   matlab.ui.control.CheckBox
        PontozottvonalakCheckBox    matlab.ui.control.CheckBox
        SVGexporttpusaButtonGroup   matlab.ui.container.ButtonGroup
        StatikusexportButton        matlab.ui.control.RadioButton
        InteraktvexportButton       matlab.ui.control.RadioButton
        ExportlsButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        fname = '';
        dname = '.';
        dottedEnabled = false;
        dashedEnabled = false;
        interactive = true;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExportlsButton
        function ExportlsButtonPushed(app, event)
            if ~isempty(regexp(app.fname, '[/\*:?"<>|]', 'once'))
                errordlg('Invalid file name | Hibás fájl név','ERROR');
            elseif strlength(app.fname) > 250
                errordlg('Too long filename | Túl hosszú fájl név','ERROR');
            else
               
                fig = get(groot,'CurrentFigure');

                if isempty(fig)
                    errordlg('Could not find current figure | Nem található koordinátarendszer ablak!','ERROR');
                    return;
                end

                ax = fig.CurrentAxes;

                if isempty(ax)
                    errordlg('Could not find current axes | Nem találhatóak a koordinátarendszer tengelyei!','ERROR');
                    return;
                end

                try
                    exportsvg(gca, strcat(app.dname,'/',strtrim(app.fname)), app.dashedEnabled, app.dottedEnabled, app.interactive, app.KpmretepxEditField.Value, app.KpeltolsaxpxEditField.Value, app.KpeltolsaypxEditField.Value);
                catch e
                    ErrorMessage=e;
                    errordlg(ErrorMessage.message,'ERROR');
                end

            end
            
        end

        % Button pushed function: TallzsButton
        function TallzsButtonPushed(app, event)
            app.dname = uigetdir(matlabroot,'MATLAB Root Folder');
            if isequal(app.dname, 0)
                app.dname = '.';
            end
        end

        % Value changed function: PontozottvonalakCheckBox
        function PontozottvonalakCheckBoxValueChanged(app, event)
            app.dottedEnabled = app.PontozottvonalakCheckBox.Value;
        end

        % Value changed function: SzaggatottvonalakCheckBox
        function SzaggatottvonalakCheckBoxValueChanged(app, event)
            app.dashedEnabled = app.SzaggatottvonalakCheckBox.Value;
        end

        % Selection changed function: SVGexporttpusaButtonGroup
        function SVGexporttpusaButtonGroupSelectionChanged(app, event)
            selectedButton = app.SVGexporttpusaButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Statikus export'
                    app.interactive = false;
                case 'Interaktív export'
                    app.interactive = true;
            end
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            %if()
            %copyobj(get(gca,'Parent'), app.UIAxes);
        end

        % Value changed function: FjlnvEditField
        function FjlnvEditFieldValueChanged(app, event)
            app.fname = app.FjlnvEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ExportlsButton = uibutton(app.UIFigure, 'push');
            app.ExportlsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportlsButtonPushed, true);
            app.ExportlsButton.Text = 'Exportálás';
            app.SVGexporttpusaButtonGroup = uibuttongroup(app.UIFigure);
            app.SVGexporttpusaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SVGexporttpusaButtonGroupSelectionChanged, true);
            app.SVGexporttpusaButtonGroup.Title = 'SVG export típusa';
            app.InteraktvexportButton = uiradiobutton(app.SVGexporttpusaButtonGroup);
            app.InteraktvexportButton.Text = 'Interaktív export';
            app.InteraktvexportButton.Value = true;
            app.StatikusexportButton = uiradiobutton(app.SVGexporttpusaButtonGroup);
            app.StatikusexportButton.Text = 'Statikus export';
            app.PontozottvonalakCheckBox = uicheckbox(app.UIFigure);
            app.PontozottvonalakCheckBox.ValueChangedFcn = createCallbackFcn(app, @PontozottvonalakCheckBoxValueChanged, true);
            app.PontozottvonalakCheckBox.Text = 'Pontozott vonalak';
            app.SzaggatottvonalakCheckBox = uicheckbox(app.UIFigure);
            app.SzaggatottvonalakCheckBox.ValueChangedFcn = createCallbackFcn(app, @SzaggatottvonalakCheckBoxValueChanged, true);
            app.SzaggatottvonalakCheckBox.Text = 'Szaggatott vonalak';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Figyelem! A vonal stílusok elképzelhető, hogy '; 'teljesítményi problémákhoz vezethetnek, '; 'vagy nem az egész vonalat fedik le.'};
            app.TallzsButton = uibutton(app.UIFigure, 'push');
            app.TallzsButton.ButtonPushedFcn = createCallbackFcn(app, @TallzsButtonPushed, true);
            app.TallzsButton.Text = 'Tallózás';
            app.FjlnvEditFieldLabel = uilabel(app.UIFigure);
            app.FjlnvEditFieldLabel.Text = 'Fájl név:';
            app.FjlnvEditField = uieditfield(app.UIFigure, 'text');
            app.FjlnvEditField.ValueChangedFcn = createCallbackFcn(app, @FjlnvEditFieldValueChanged, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Fájl helyének tallózása:';
            app.KpeltolsaxpxEditFieldLabel = uilabel(app.UIFigure);
            app.KpeltolsaxpxEditFieldLabel.Text = 'Kép eltolása x (px):';
            app.KpeltolsaxpxEditField = uieditfield(app.UIFigure, 'numeric');
            app.KpeltolsaxpxEditField.Value = 250;
            app.KpmretepxEditFieldLabel = uilabel(app.UIFigure);
            app.KpmretepxEditFieldLabel.Text = 'Kép mérete (px):';
            app.KpmretepxEditField = uieditfield(app.UIFigure, 'numeric');
            app.KpmretepxEditField.Value = 500;
            app.KpeltolsaypxEditFieldLabel = uilabel(app.UIFigure);
            app.KpeltolsaypxEditFieldLabel.Text = 'Kép eltolása y (px):';
            app.KpeltolsaypxEditField = uieditfield(app.UIFigure, 'numeric');
            app.KpeltolsaypxEditField.Value = 500;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SVGExport

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end