classdef GeomatplotApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Toolbar                      matlab.ui.container.Toolbar
        Tools                        matlab.ui.container.toolbar.ToggleTool
        TabGroup                     matlab.ui.container.TabGroup
        BasicTools                   matlab.ui.container.Tab
        BasicToolsGrid               matlab.ui.container.GridLayout
        ClosestPointButton           matlab.ui.control.Button
        MirrorButton                 matlab.ui.control.Button
        IntersectButton              matlab.ui.control.Button
        PolygonButton                matlab.ui.control.Button
        Midpoint2Button              matlab.ui.control.Button
        MoveButton                   matlab.ui.control.Button
        PointButton                  matlab.ui.control.Button
        CircleTools                  matlab.ui.container.Tab
        CircleToolsGrid              matlab.ui.container.GridLayout
        CircularArcButton            matlab.ui.control.Button
        Circle2Button                matlab.ui.control.Button
        Circle3Button                matlab.ui.control.Button
        LineTools                    matlab.ui.container.Tab
        LineToolsGrid                matlab.ui.container.GridLayout
        VectorButton                 matlab.ui.control.Button
        RayButton                    matlab.ui.control.Button
        PerpendicularLine2Button     matlab.ui.control.Button
        PerpendicularLine3Button     matlab.ui.control.Button
        AngleBisector4Button         matlab.ui.control.Button
        AngleBisector3Button         matlab.ui.control.Button
        PerpendicularBisectorButton  matlab.ui.control.Button
        LineButton                   matlab.ui.control.Button
        SegmentButton                matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        model
        selectedGeometry
        selectPanel
        colorPanel
        shouldCreatePanel = false
    end

    methods(Access = private, Hidden)
        function handleSelect(app,data)
            newSelection = data{1};
            app.deselect();
            acceptTypes.selectGeometry(newSelection);
            app.selectedGeometry = newSelection;
            app.shouldCreatePanel = true;
        end

        function createPropertiesPanel(app)
            if ~isempty(app.selectPanel); delete(app.selectPanel); end
            if ~isa(app.selectedGeometry,'point_base'); return; end
            
            currentPoint = app.UIFigure.CurrentPoint(1:2);

            panel = uipanel(app.UIFigure);
            panel.Position = [currentPoint + [10 -50], 35, 35];
            grid = uigridlayout(panel);
            grid.ColumnWidth = { 25 };
            grid.RowHeight = {25};
            grid.Padding = [5 5 5 5];

            colors = {[1 0 0], [0 1 0], [0 0 1], [0 0 0]};
            colorDropdown = uidropdown(grid);
            colorDropdown.ValueChangedFcn = createCallbackFcn(app, @setColor, true);
            colorDropdown.Layout.Row = 1;
            colorDropdown.Layout.Column = 1;
            colorDropdown.Items = repmat({''}, numel(colors), 1);
            colorDropdown.ItemsData = colors;
            for i = 1:numel(colors)
                style = uistyle('BackgroundColor',colors{i});
                addStyle(colorDropdown,style,"item",i);
            end
            %Setting it directly could be a problem if the color is not in
            %the list
            colorDropdown.Value = app.selectedGeometry.fig.Color;            
            app.selectPanel = panel;
        end

        function setColor(app,p)
            app.selectedGeometry.fig.Color = p.Value;
        end

        function deselect(app)
            acceptTypes.deselectGeometry(app.selectedGeometry);
            delete(app.selectPanel); 
        end

        function addHandlerFcns(app,acceptFcn,drawFcn)
            acceptTypes.resetDataSelection(app.model.clickData);
            app.deselect();
            app.model.addHandlerFcns(acceptFcn,drawFcn);
        end

        function WindowMouseUp(app,fig,~)
            switch fig.SelectionType
                case 'normal'
                    if app.shouldCreatePanel
                        app.createPropertiesPanel(); 
                        app.shouldCreatePanel = false;
                    end
            end
        end

        function drawPoint(app,data)
            point = data{1};
            Point(app.model,[point.x point.y],'b');
        end

        function drawSegment(app,data)
            Segment(app.model,data{1},data{2});
        end

        function drawLine(app,data)
            Line(app.model,data{1},data{2});
        end

        function drawCircle3(app,data)
            Circle(app.model,data{1},data{2},data{3});
        end

        function drawCircle2(app,data)
            Circle(app.model,data{1},data{2});
        end

        function drawMidpoint2(app,data)
            Midpoint(app.model,data{1},data{2});
        end

        function drawPerpendicularBisector(app,data)
            PerpendicularBisector(app.model,data{1},data{2},':');
        end

        function drawAngleBisector3(app,data)
            AngleBisector(app.model,data{1},data{2},data{3},':');
        end
    
        function drawPolygon(app,data)
            Polygon(app.model,data);
        end

        function drawAngleBisector4(app,data)
            AngleBisector(app.model,data{1},data{2},data{3},data{4},':');
        end

        function drawCircularArc(app,data)
            CircularArc(app.model,data{1},data{2},data{3});
        end

        function drawPerpendicularLine3(app,data)
            PerpendicularLine(app.model,data{1},data{2},data{3});
        end

        function drawPerpendicularLine2(app,data)
            PerpendicularLine(app.model,data{1},data{2});
        end

        function drawRay(app,data)
            Ray(app.model,data{1},data{2});
        end

        function drawVector(app,data)
            Vector(app.model,data{1},data{2});
        end

        function drawIntersection(app,data)
            Intersect(app.model,data{1},data{2});
        end

        function drawMirror(app,data)
            Mirror(app.model,data{1},data{2});
        end

        function drawClosestPoint(app,data)
            ClosestPoint(app.model,data{1},data{2});
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.model = Geomatplot(app.UIAxes);
            %the default state is selection
            app.addHandlerFcns(@acceptTypes.acceptSelect,@app.handleSelect);
            %https://stackoverflow.com/questions/56259686
            addlistener(app.UIFigure,'WindowMouseRelease',@app.WindowMouseUp);
        end

        % Callback function: Tools
        function ToolsClicked(app, event)
            app.TabGroup.Visible = ~app.TabGroup.Visible;
        end

        % Button pushed function: PointButton
        function PointButtonPushed(app, event)
            app.addHandlerFcns(@acceptTypes.acceptPoint,@app.drawPoint);
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            app.addHandlerFcns(@acceptTypes.acceptSelect,@app.handleSelect);
        end

        % Button pushed function: SegmentButton
        function SegmentButtonPushed(app, event)
            app.addHandlerFcns(@acceptTypes.acceptSegmentorLine,@app.drawSegment);
        end

        % Button pushed function: LineButton
        function LineButtonPushed(app, event)
            app.addHandlerFcns(@acceptTypes.acceptSegmentorLine,@app.drawLine);
        end

        % Button pushed function: Circle3Button
        function Circle3ButtonPushed(app, event)
            app.addHandlerFcns(@acceptTypes.acceptCircle3,@app.drawCircle3);
        end

        % Button pushed function: Circle2Button
        function Circle2ButtonPushed(app, event)
            app.addHandlerFcns(@acceptTypes.acceptCircle2,@app.drawCircle2);
        end

        % Button pushed function: Midpoint2Button
        function Midpoint2ButtonPushed(app, event)
            app.addHandlerFcns(@acceptTypes.acceptMidpoint2,@app.drawMidpoint2);
        end

        % Button pushed function: PerpendicularBisectorButton
        function PerpendicularBisectorButtonPushed(app, event)
            app.addHandlerFcns(@acceptTypes.acceptMidpoint2,@app.drawPerpendicularBisector);
        end

        % Button pushed function: AngleBisector3Button
        function AngleBisector3ButtonPushed(app, event)
            app.addHandlerFcns(@acceptTypes.acceptAngleBisector3,@app.drawAngleBisector3);
        end

        % Button pushed function: PolygonButton
        function PolygonButtonPushed(app, event)
            app.addHandlerFcns(@acceptTypes.acceptPolygon,@app.drawPolygon);
        end

        % Button pushed function: AngleBisector4Button
        function AngleBisector4ButtonPushed(app, event)
            app.addHandlerFcns(@acceptTypes.acceptAngleBisector4,@app.drawAngleBisector4);
        end

        % Button pushed function: CircularArcButton
        function CircularArcButtonPushed(app, event)
            app.addHandlerFcns(@acceptTypes.acceptCircularArc,@app.drawCircularArc);
        end

        % Button pushed function: PerpendicularLine3Button
        function PerpendicularLine3ButtonPushed(app, event)
            app.addHandlerFcns(@acceptTypes.acceptPerpendicularLine3,@app.drawPerpendicularLine3);
        end

        % Button pushed function: PerpendicularLine2Button
        function PerpendicularLine2ButtonPushed(app, event)
            app.addHandlerFcns(@acceptTypes.acceptPerpendicularLine2,@app.drawPerpendicularLine2);
        end

        % Button pushed function: RayButton
        function RayButtonPushed(app, event)
            app.addHandlerFcns(@acceptTypes.acceptRay,@app.drawRay);
        end

        % Button pushed function: VectorButton
        function VectorButtonPushed(app, event)
            app.addHandlerFcns(@acceptTypes.acceptVector,@app.drawVector);
        end

        % Button pushed function: IntersectButton
        function IntersectButtonPushed(app, event)
            app.addHandlerFcns(@acceptTypes.acceptIntersection,@app.drawIntersection);
        end

        % Button pushed function: MirrorButton
        function MirrorButtonPushed(app, event)
            app.addHandlerFcns(@acceptTypes.acceptMirror,@app.drawMirror);
        end

        % Button pushed function: ClosestPointButton
        function ClosestPointButtonPushed(app, event)
            app.addHandlerFcns(@acceptTypes.acceptClosestPoint,@app.drawClosestPoint);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'GeomatplotGui';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.Tools = uitoggletool(app.Toolbar);
            app.Tools.ClickedCallback = createCallbackFcn(app, @ToolsClicked, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Main View')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BasicTools = uitab(app.TabGroup);
            app.BasicTools.Title = 'Basic Tools';
            app.BasicToolsGrid = uigridlayout(app.BasicTools);
            app.PointButton = uibutton(app.BasicToolsGrid, 'push');
            app.PointButton.ButtonPushedFcn = createCallbackFcn(app, @PointButtonPushed, true);
            app.PointButton.Icon = fullfile(pathToMLAPP, 'resources', 'Point.png');
            app.PointButton.Tooltip = {'Place a point.'};
            app.PointButton.Text = '';
            app.MoveButton = uibutton(app.BasicToolsGrid, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Icon = fullfile(pathToMLAPP, 'resources', 'Move.png');
            app.MoveButton.Tooltip = {'Move'};
            app.MoveButton.Text = '';
            app.Midpoint2Button = uibutton(app.BasicToolsGrid, 'push');
            app.Midpoint2Button.ButtonPushedFcn = createCallbackFcn(app, @Midpoint2ButtonPushed, true);
            app.Midpoint2Button.Icon = fullfile(pathToMLAPP, 'resources', 'Midpoint.png');
            app.Midpoint2Button.Tooltip = {'Create Midpoint between two points.'};
            app.Midpoint2Button.Text = '';
            app.PolygonButton = uibutton(app.BasicToolsGrid, 'push');
            app.PolygonButton.ButtonPushedFcn = createCallbackFcn(app, @PolygonButtonPushed, true);
            app.PolygonButton.Icon = fullfile(pathToMLAPP, 'resources', 'Polygon.png');
            app.PolygonButton.Tooltip = {'Select all points then the first one.'};
            app.PolygonButton.Text = '';
            app.IntersectButton = uibutton(app.BasicToolsGrid, 'push');
            app.IntersectButton.ButtonPushedFcn = createCallbackFcn(app, @IntersectButtonPushed, true);
            app.IntersectButton.Icon = fullfile(pathToMLAPP, 'resources', 'Intersect.png');
            app.IntersectButton.Tooltip = {'Creates an intesection between two selected figures.'};
            app.IntersectButton.Text = '';
            app.MirrorButton = uibutton(app.BasicToolsGrid, 'push');
            app.MirrorButton.ButtonPushedFcn = createCallbackFcn(app, @MirrorButtonPushed, true);
            app.MirrorButton.Icon = fullfile(pathToMLAPP, 'resources', 'Mirror.png');
            app.MirrorButton.Tooltip = {'Figure to be mirrored, then center.'};
            app.MirrorButton.Text = '';
            app.ClosestPointButton = uibutton(app.BasicToolsGrid, 'push');
            app.ClosestPointButton.ButtonPushedFcn = createCallbackFcn(app, @ClosestPointButtonPushed, true);
            app.ClosestPointButton.Icon = fullfile(pathToMLAPP, 'resources', 'ClosestPoint.png');
            app.ClosestPointButton.Tooltip = {'Closest point to a figure. '};
            app.ClosestPointButton.Text = '';
            app.CircleTools = uitab(app.TabGroup);
            app.CircleTools.Title = 'Circles';
            app.CircleToolsGrid = uigridlayout(app.CircleTools);
            app.Circle3Button = uibutton(app.CircleToolsGrid, 'push');
            app.Circle3Button.ButtonPushedFcn = createCallbackFcn(app, @Circle3ButtonPushed, true);
            app.Circle3Button.Icon = fullfile(pathToMLAPP, 'resources', 'Circle3.png');
            app.Circle3Button.Tooltip = {'Draws a circumscribed circle going through points A, B, and C.'};
            app.Circle3Button.Text = '';
            app.Circle2Button = uibutton(app.CircleToolsGrid, 'push');
            app.Circle2Button.ButtonPushedFcn = createCallbackFcn(app, @Circle2ButtonPushed, true);
            app.Circle2Button.Icon = fullfile(pathToMLAPP, 'resources', 'Circle2.png');
            app.Circle2Button.Tooltip = {'Draws a circle around point A extending to point B.'};
            app.Circle2Button.Text = '';
            app.CircularArcButton = uibutton(app.CircleToolsGrid, 'push');
            app.CircularArcButton.ButtonPushedFcn = createCallbackFcn(app, @CircularArcButtonPushed, true);
            app.CircularArcButton.Icon = fullfile(pathToMLAPP, 'resources', 'CircularArc.png');
            app.CircularArcButton.Tooltip = {'Draws a circular arc around O starting from B until it meets the OC line in   anticlockwise direction.'};
            app.CircularArcButton.Text = '';
            app.LineTools = uitab(app.TabGroup);
            app.LineTools.Title = 'Lines';
            app.LineToolsGrid = uigridlayout(app.LineTools);
            app.SegmentButton = uibutton(app.LineToolsGrid, 'push');
            app.SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentButtonPushed, true);
            app.SegmentButton.Icon = fullfile(pathToMLAPP, 'resources', 'Segment.png');
            app.SegmentButton.Tooltip = {'Draws a line segment between point A and point B.'};
            app.SegmentButton.Text = '';
            app.LineButton = uibutton(app.LineToolsGrid, 'push');
            app.LineButton.ButtonPushedFcn = createCallbackFcn(app, @LineButtonPushed, true);
            app.LineButton.Icon = fullfile(pathToMLAPP, 'resources', 'Line.png');
            app.LineButton.Tooltip = {'Draws a line between point A and point B extending past both points.'};
            app.LineButton.Text = '';
            app.PerpendicularBisectorButton = uibutton(app.LineToolsGrid, 'push');
            app.PerpendicularBisectorButton.ButtonPushedFcn = createCallbackFcn(app, @PerpendicularBisectorButtonPushed, true);
            app.PerpendicularBisectorButton.Icon = fullfile(pathToMLAPP, 'resources', 'PerpendicularBisector.png');
            app.PerpendicularBisectorButton.Tooltip = {'Draws the perpendicular bisector line seperating A and B points.'};
            app.PerpendicularBisectorButton.Text = '';
            app.AngleBisector3Button = uibutton(app.LineToolsGrid, 'push');
            app.AngleBisector3Button.ButtonPushedFcn = createCallbackFcn(app, @AngleBisector3ButtonPushed, true);
            app.AngleBisector3Button.Icon = fullfile(pathToMLAPP, 'resources', 'AngleBisector3.png');
            app.AngleBisector3Button.Tooltip = {'Draws the angular bisector line through B and between A and C.'};
            app.AngleBisector3Button.Text = '';
            app.AngleBisector4Button = uibutton(app.LineToolsGrid, 'push');
            app.AngleBisector4Button.ButtonPushedFcn = createCallbackFcn(app, @AngleBisector4ButtonPushed, true);
            app.AngleBisector4Button.Icon = fullfile(pathToMLAPP, 'resources', 'AngleBisector4.png');
            app.AngleBisector4Button.Tooltip = {'Draws the two angular bisectors of the lines AB and CD.'};
            app.AngleBisector4Button.Text = '';
            app.PerpendicularLine3Button = uibutton(app.LineToolsGrid, 'push');
            app.PerpendicularLine3Button.ButtonPushedFcn = createCallbackFcn(app, @PerpendicularLine3ButtonPushed, true);
            app.PerpendicularLine3Button.Icon = fullfile(pathToMLAPP, 'resources', 'PerpendicularLine3.png');
            app.PerpendicularLine3Button.Tooltip = {'Draws a perpendicular line from A to BC.'};
            app.PerpendicularLine3Button.Text = '';
            app.PerpendicularLine2Button = uibutton(app.LineToolsGrid, 'push');
            app.PerpendicularLine2Button.ButtonPushedFcn = createCallbackFcn(app, @PerpendicularLine2ButtonPushed, true);
            app.PerpendicularLine2Button.Icon = fullfile(pathToMLAPP, 'resources', 'PerpendicularLine2.png');
            app.PerpendicularLine2Button.Tooltip = {'Draws a perpendicular line from A to AB.'};
            app.PerpendicularLine2Button.Text = '';
            app.RayButton = uibutton(app.LineToolsGrid, 'push');
            app.RayButton.ButtonPushedFcn = createCallbackFcn(app, @RayButtonPushed, true);
            app.RayButton.Icon = fullfile(pathToMLAPP, 'resources', 'Ray.png');
            app.RayButton.Tooltip = {'Draws a ray between point A and point B extending past point B.'};
            app.RayButton.Text = '';
            app.VectorButton = uibutton(app.LineToolsGrid, 'push');
            app.VectorButton.ButtonPushedFcn = createCallbackFcn(app, @VectorButtonPushed, true);
            app.VectorButton.Icon = fullfile(pathToMLAPP, 'resources', 'Vector.png');
            app.VectorButton.Tooltip = {'Draws a vector from A to B.'};
            app.VectorButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GeomatplotApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end