classdef GeomatplotGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        Tools                           matlab.ui.container.toolbar.ToggleTool
        Sliders                         matlab.ui.container.toolbar.ToggleTool
        Distances                       matlab.ui.container.toolbar.ToggleTool
        FigureTools                     matlab.ui.container.toolbar.ToggleTool
        DistancesPanel                  matlab.ui.container.Panel
        DistanceLayout                  matlab.ui.container.GridLayout
        DistanceList                    matlab.ui.container.GridLayout
        AddDistanceButton               matlab.ui.control.Button
        FigureToolsPanel                matlab.ui.container.Panel
        FigureToolsLayout               matlab.ui.container.GridLayout
        ExportButton                    matlab.ui.control.Button
        ClearAllButton                  matlab.ui.control.Button
        ImportButton                    matlab.ui.control.Button
        SliderPanel                     matlab.ui.container.Panel
        SliderLayout                    matlab.ui.container.GridLayout
        SliderList                      matlab.ui.container.GridLayout
        AddSliderButton                 matlab.ui.control.Button
        EndSelectionButton              matlab.ui.control.Button
        ToolTabs                        matlab.ui.container.TabGroup
        BasicTools                      matlab.ui.container.Tab
        BasicToolsGrid                  matlab.ui.container.GridLayout
        MPolygonButton                  matlab.ui.control.Button
        MirrorSegmentButton             matlab.ui.control.Button
        CentroidPointButton             matlab.ui.control.Button
        ClosestPointButton              matlab.ui.control.Button
        MirrorButton                    matlab.ui.control.Button
        IntersectButton                 matlab.ui.control.Button
        DPolygonButton                  matlab.ui.control.Button
        MidpointButton                  matlab.ui.control.Button
        MoveButton                      matlab.ui.control.Button
        PointButton                     matlab.ui.control.Button
        CircleTools                     matlab.ui.container.Tab
        CircleToolsGrid                 matlab.ui.container.GridLayout
        CircularArcButton               matlab.ui.control.Button
        Circle2Button                   matlab.ui.control.Button
        Circle3Button                   matlab.ui.control.Button
        LineTools                       matlab.ui.container.Tab
        LineToolsGrid                   matlab.ui.container.GridLayout
        ParallelLineButton              matlab.ui.control.Button
        SegmentSequenceTrianglesButton  matlab.ui.control.Button
        SegmentSequenceLinesButton      matlab.ui.control.Button
        SegmentSequenceStripButton      matlab.ui.control.Button
        VectorButton                    matlab.ui.control.Button
        RayButton                       matlab.ui.control.Button
        PerpendicularLine2Button        matlab.ui.control.Button
        PerpendicularLine3Button        matlab.ui.control.Button
        AngleBisector4Button            matlab.ui.control.Button
        AngleBisector3Button            matlab.ui.control.Button
        PerpendicularBisectorButton     matlab.ui.control.Button
        LineButton                      matlab.ui.control.Button
        SegmentButton                   matlab.ui.control.Button
        CurveTools                      matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        Bezier3Button                   matlab.ui.control.Button
        Bezier2Button                   matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    properties (Access = private)
        model
        selectedGeometry = []
        panel
        shouldCreatePanel = false
        shouldAccept = false
        sliderDefaultPos = [-10 10]
        sliderNextPos = [-10 10]
        sliderYPadding = 0.1
        funcDir = ''
        polyref = []
    end

    methods(Access = public)
        function createDistanceUI(app,dist)
            app.DistanceList.RowHeight = repmat({40}, length(app.DistanceList.RowHeight) + 1, 1);
            DistanceSlot(app.DistanceList,dist);
        end

        function createSliderUI(app,val)
            app.SliderList.RowHeight = repmat({40}, length(app.SliderList.RowHeight) + 1, 1);
            SliderSlot(app.SliderList,val);
            app.sliderNextPos = app.sliderNextPos - [0 app.sliderYPadding];
        end
    end
    
    methods(Access = private, Hidden)
        function handleMove(app,~,data)
            newSelection = data{1};
            app.deselect();
            AcceptTypes.selectGeometry(newSelection);
            app.selectedGeometry = newSelection;
            app.shouldCreatePanel = true;
        end

        function createPropertiesPanel(app)
            delete(app.panel);
            
            currentPoint = app.UIFigure.CurrentPoint(1:2);
            app.panel = PropertiesPanel(app.UIFigure,currentPoint,app.selectedGeometry);
            addlistener(app.panel,'RenamedLabel',@app.renamedLabel);
        end

        function deselect(app)
            AcceptTypes.deselectGeometry(app.selectedGeometry);
            delete(app.panel);
            app.selectedGeometry = [];
        end

        function addHandlerFcns(app,acceptFcn,drawFcn,errorFcn)
            AcceptTypes.resetDataSelection(app.model.clickData);
            app.deselect();
            app.EndSelectionButton.Visible = 'off';
            if ishandle(app.polyref); delete(app.polyref); end
            app.model.addHandlerFcns(acceptFcn,drawFcn,errorFcn);
        end

        function WindowMouseRelease(app,fig,~)
            switch fig.SelectionType
                case 'normal'
                    if app.shouldCreatePanel
                        app.createPropertiesPanel(); 
                        app.shouldCreatePanel = false;
                    end
            end
        end

        function createSliderUIs(app)
            %Create slider UIs after file import
            go = app.model;
            depFields = fieldnames(go.deps);
            for i = 1:length(depFields)
                dep = go.deps.(depFields{i});
                if isa(dep,'dscalar') && ...
                    contains(functions(dep.callback).function,'Scalar/internalcallback') && ...
                    contains(functions( ...
                    functions(dep.callback).workspace{1}.usercallback).function,'@(dp)(dp(1)')
                    app.createSliderUI(dep);
                end
            end
        end

        function renamedLabel(app,src,~)
            uDistances = arrayfun(@(x) x.UserData, app.DistanceList.Children);
            %Update label name in distance UIs
            for i = 1:length(uDistances)
                distUI = uDistances(i);
                distHandle = distUI.distHandle;
                if isequal(distHandle.inputs{1},src.geometry) || ...
                   isequal(distHandle.inputs{2},src.geometry)
                   distUI.updateText();
                end
            end
        end

        function sequenceError(app,o,data,errorFcn)
            app.shouldAccept = false;
            errorFcn(o,data);
        end

        function drawSegmentSequence(app,o,data,breakEvery)
            DrawGeometry.drawSegmentSequence(o,data,breakEvery);
            app.shouldAccept = false;
        end

        function drawCentroidPoint(app,o,data)
            DrawGeometry.drawCentroidPoint(o,data);
            app.shouldAccept = false;
        end

        function drawDistance(app,~,data)
            dist = Distance(app.model,data{1},data{2});
            app.createDistanceUI(dist);
        end

        function drawMPolygon(app,o,data)
            DrawGeometry.drawMPolygon(o,data);
            app.shouldAccept = false;
        end

        function accepted = acceptMPolygon(app,data)
            %Draw a temporary polygon to show current selection
            accepted = AcceptTypes.acceptMPolygon(data,app.shouldAccept);
            if accepted ~= 0
                if ishandle(app.polyref); delete(app.polyref); end
                return; 
            end
            dlength = length(data);
            nextpos = [data{dlength}.x data{dlength}.y];
            if dlength == 1
                app.polyref = drawpolygon(app.model.ax,"Position",nextpos,"Color","blue");
            else
                app.polyref.Position = [app.polyref.Position; nextpos];
            end
        end

        function setTitle(app,state)
            app.UIFigure.Name = "GeomatplotGui (" + state + ")";
        end

        function moveState(app)
            app.setTitle("Move");
            app.addHandlerFcns(@AcceptTypes.acceptMove, @app.handleMove,@(o,data) {});
        end
        
        function createGeomatplotObject(app)
            app.model = Geomatplot(app.UIAxes);
            %the default state is move
            app.moveState();
        end

        function disableAllTools(app)
            app.Sliders.State = 'off';
            app.SliderPanel.Visible = 'off';
            app.FigureTools.State = 'off';
            app.FigureToolsPanel.Visible = 'off';
            app.Tools.State = 'off';
            app.ToolTabs.Visible = 'off';
            app.Distances.State = 'off';
            app.DistancesPanel.Visible = 'off';
        end

        function switchToToolbarElem(app,elem)
            isOn = false;
            switch elem
                case "Tools"; isOn = app.Tools.State;
                case "Sliders"; isOn = app.Sliders.State;
                case "FigureTools"; isOn = app.FigureTools.State;
                case "Distances"; isOn = app.Distances.State;
                otherwise
                    disp("Tool not found!");
            end

            app.disableAllTools();
            if ~isOn; return; end
            switch elem
                case "Tools"
                    app.Tools.State = 'on';
                    app.ToolTabs.Visible = 'on';
                case "Sliders"
                    app.Sliders.State = 'on';
                    app.SliderPanel.Visible = 'on';
                case "FigureTools"
                    app.FigureTools.State = 'on';
                    app.FigureToolsPanel.Visible = 'on';
                case "Distances"
                    app.Distances.State = 'on';
                    app.DistancesPanel.Visible = 'on';
                otherwise
                    disp("Tool not found!");
            end
        end

        function removeTempFolder(app)
            if exist(app.funcDir, 'dir')
                if ~isdeployed; rmpath(app.funcDir); end
                rmdir(app.funcDir, 's');
            end
        end

        function clearAll(app)
            app.deselect();
            delete(app.model);
            clear app.model;
            app.model = [];
            app.UIAxes.UserData = [];
            cla(app.UIAxes);
            %https://stackoverflow.com/questions/48345311/find-and-delete-listeners
            listeners = app.UIAxes.AutoListeners__;
            for i = 1:numel(listeners)
                delete(listeners{i});
            end
            %Reset UI
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            delete(app.DistanceList.Children);
            app.DistanceList.RowHeight = {};
            delete(app.SliderList.Children);
            app.SliderList.RowHeight = {};
            app.sliderNextPos = app.sliderDefaultPos;
            app.removeTempFolder();
            app.createGeomatplotObject();
        end

        function addSlider(app,range)
            [val,~,~] = drawSliderX(app.model,range,app.sliderNextPos,0.5,'Visible','off');
            app.createSliderUI(val);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if ~isdeployed; app.funcDir = [pwd '\temp_fcns']; end
            %Bypass initial Geomatplot creation
            app.createGeomatplotObject();
            a = Point(app.model,[-10 -10]); %drawing constructor
            Point(app.model,a-[1 1]); %expression_base constructor
            cf = get(groot,"CurrentFigure");
            if ishandle(cf); delete(cf); end
            app.clearAll();
            %https://stackoverflow.com/questions/56259686
            addlistener(app.UIFigure,'WindowMouseRelease',@app.WindowMouseRelease);
        end

        % Callback function: Tools
        function ToolsClicked(app, event)
            app.switchToToolbarElem("Tools");
        end

        % Button pushed function: PointButton
        function PointButtonPushed(app, event)
           app.setTitle("Point");
            app.addHandlerFcns(@AcceptTypes.acceptPoint, ...
                @DrawGeometry.drawPoint,@ErrorHandler.pointError);
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            app.moveState();
        end

        % Button pushed function: SegmentButton
        function SegmentButtonPushed(app, event)
            app.setTitle("Segment");
            app.addHandlerFcns(@AcceptTypes.acceptSegmentorLine, ...
                @DrawGeometry.drawSegment,@ErrorHandler.segmentOrLineError);
        end

        % Button pushed function: LineButton
        function LineButtonPushed(app, event)
            app.setTitle("Line");
            app.addHandlerFcns(@AcceptTypes.acceptSegmentorLine, ...
                @DrawGeometry.drawLine,@ErrorHandler.segmentOrLineError);
        end

        % Button pushed function: Circle3Button
        function Circle3ButtonPushed(app, event)
            app.setTitle("Circle 3P");
            app.addHandlerFcns(@AcceptTypes.acceptCircle3, ...
                @DrawGeometry.drawCircle3,@ErrorHandler.circle3Error);
        end

        % Button pushed function: Circle2Button
        function Circle2ButtonPushed(app, event)
            app.setTitle("Circle 2P");
            app.addHandlerFcns(@AcceptTypes.acceptCircle2, ...
                @DrawGeometry.drawCircle2,@ErrorHandler.circle2Error);
        end

        % Button pushed function: MidpointButton
        function MidpointButtonPushed(app, event)
            app.setTitle("Midpoint");
            app.addHandlerFcns(@AcceptTypes.acceptMidpoint, ...
                @DrawGeometry.drawMidpoint,@ErrorHandler.midpointError);
        end

        % Button pushed function: PerpendicularBisectorButton
        function PerpendicularBisectorButtonPushed(app, event)
            app.setTitle("Perpendicular Bisector");
            app.addHandlerFcns(@AcceptTypes.acceptPerpendicularBisector, ...
                @DrawGeometry.drawPerpendicularBisector, ...
                @ErrorHandler.perpendicularBisectorError);
        end

        % Button pushed function: AngleBisector3Button
        function AngleBisector3ButtonPushed(app, event)
            app.setTitle("Angle Bisector 3P");
            app.addHandlerFcns(@AcceptTypes.acceptAngleBisector3, ...
                @DrawGeometry.drawAngleBisector3, ...
                @ErrorHandler.angleBisector3Error);
        end

        % Button pushed function: DPolygonButton
        function DPolygonButtonPushed(app, event)
            app.setTitle("Dependent Polygon");
            app.addHandlerFcns(@AcceptTypes.acceptDPolygon, ...
                @DrawGeometry.drawDPolygon,@ErrorHandler.dpolygonError);
        end

        % Button pushed function: AngleBisector4Button
        function AngleBisector4ButtonPushed(app, event)
            app.setTitle("Angle Bisector 4P");
            app.addHandlerFcns(@AcceptTypes.acceptAngleBisector4, ...
                @DrawGeometry.drawAngleBisector4, ...
                @ErrorHandler.angleBisector4Error);
        end

        % Button pushed function: CircularArcButton
        function CircularArcButtonPushed(app, event)
            app.setTitle("Circular Arc");
            app.addHandlerFcns(@AcceptTypes.acceptCircularArc, ...
                @DrawGeometry.drawCircularArc,@ErrorHandler.circularArcError);
        end

        % Button pushed function: PerpendicularLine3Button
        function PerpendicularLine3ButtonPushed(app, event)
            app.setTitle("Perpendicular Line 3P");
            app.addHandlerFcns(@AcceptTypes.acceptPerpendicularLine3, ...
                @DrawGeometry.drawPerpendicularLine3, ...
                @ErrorHandler.perpendicularLine3Error);
        end

        % Button pushed function: PerpendicularLine2Button
        function PerpendicularLine2ButtonPushed(app, event)
            app.setTitle("Perpendicular Line 2P");
            app.addHandlerFcns(@AcceptTypes.acceptPerpendicularLine2, ...
                @DrawGeometry.drawPerpendicularLine2, ...
                @ErrorHandler.perpendicularLine2Error);
        end

        % Button pushed function: RayButton
        function RayButtonPushed(app, event)
            app.setTitle("Ray");
            app.addHandlerFcns(@AcceptTypes.acceptRay, ...
                @DrawGeometry.drawRay,@ErrorHandler.rayError);
        end

        % Button pushed function: VectorButton
        function VectorButtonPushed(app, event)
            app.setTitle("Vector");
            app.addHandlerFcns(@AcceptTypes.acceptVector, ...
                @DrawGeometry.drawVector,@ErrorHandler.vectorError);
        end

        % Button pushed function: IntersectButton
        function IntersectButtonPushed(app, event)
            app.setTitle("Intersect");
            app.addHandlerFcns(@AcceptTypes.acceptIntersection, ...
                @DrawGeometry.drawIntersection,@ErrorHandler.intersectionError);
        end

        % Button pushed function: MirrorButton
        function MirrorButtonPushed(app, event)
            app.setTitle("Mirror");
            app.addHandlerFcns(@AcceptTypes.acceptMirror, ...
                @DrawGeometry.drawMirror,@ErrorHandler.mirrorError);
        end

        % Button pushed function: ClosestPointButton
        function ClosestPointButtonPushed(app, event)
            app.setTitle("Closest Point");
            app.addHandlerFcns(@AcceptTypes.acceptClosestPoint, ...
                @DrawGeometry.drawClosestPoint,@ErrorHandler.closestPointError);
        end

        % Button pushed function: SegmentSequenceStripButton
        function SegmentSequenceStripButtonPushed(app, event)
            app.setTitle("Segment Sequence Strip");
            app.addHandlerFcns( ...
                @(data) AcceptTypes.acceptSegmentSequenceStrip(data,app.shouldAccept), ...
                @(o,data) app.drawSegmentSequence(o,data,'strip'), ...
                @(o,data) app.sequenceError(o,data,@ErrorHandler.segmentSequenceStripError));
            app.EndSelectionButton.Visible = 'on';
        end

        % Button pushed function: EndSelectionButton
        function EndSelectionButtonPushed(app, event)
            app.shouldAccept = true;
            app.model.checkData();
        end

        % Button pushed function: CentroidPointButton
        function CentroidPointButtonPushed(app, event)
            app.setTitle("Centroid Point");
            app.addHandlerFcns( ...
                @(data) AcceptTypes.acceptCentroidPoint(data,app.shouldAccept), ...
                @app.drawCentroidPoint, ...
                @(o,data) app.sequenceError(o,data,@ErrorHandler.centroidPointError));
            app.EndSelectionButton.Visible = 'on';
        end

        % Button pushed function: MirrorSegmentButton
        function MirrorSegmentButtonPushed(app, event)
            app.setTitle("Mirror by Segment");
            app.addHandlerFcns(@AcceptTypes.acceptMirrorSegment, ...
                @DrawGeometry.drawMirrorSegment,@ErrorHandler.mirrorSegmentError);
        end

        % Callback function: Sliders
        function SlidersClicked(app, event)
            app.switchToToolbarElem("Sliders");
        end

        % Button pushed function: AddSliderButton
        function AddSliderButtonPushed(app, event)
            ret = inputdlg({'Enter the minimum value:', 'Enter the maximum value:'}, ...
                'Slider Range', [1 35], {'0', '1'});
            
            if isempty(ret); return; end
            min = str2double(ret{1});
            max = str2double(ret{2});
                
            if isnan(min) || isnan(max) || min >= max; return; end
            app.addSlider([min max]);
        end

        % Button pushed function: SegmentSequenceLinesButton
        function SegmentSequenceLinesButtonPushed(app, event)
            app.setTitle("Segment Sequence Lines");
            app.addHandlerFcns( ...
                @(data) AcceptTypes.acceptSegmentSequenceLines(data,app.shouldAccept), ...
                @(o,data) app.drawSegmentSequence(o,data,'lines'), ...
                @(o,data) app.sequenceError(o,data,@ErrorHandler.segmentSequenceLinesError));
            app.EndSelectionButton.Visible = 'on';
        end

        % Button pushed function: SegmentSequenceTrianglesButton
        function SegmentSequenceTrianglesButtonPushed(app, event)
            app.setTitle("Segment Sequence Triangles");
            app.addHandlerFcns( ...
                @(data) AcceptTypes.acceptSegmentSequenceTriangles(data,app.shouldAccept), ...
                @(o,data) app.drawSegmentSequence(o,data,'triangles'), ...
                @(o,data) app.sequenceError(o,data,@ErrorHandler.segmentSequenceTrianglesError));
            app.EndSelectionButton.Visible = 'on';
        end

        % Button pushed function: Bezier2Button
        function Bezier2ButtonPushed(app, event)
            app.setTitle("Quadratic Bezier");
            app.addHandlerFcns(@AcceptTypes.acceptBezier2, ...
                @DrawGeometry.drawBezier2,@ErrorHandler.bezier2Error);
        end

        % Button pushed function: Bezier3Button
        function Bezier3ButtonPushed(app, event)
            app.setTitle("Cubic Bezier");
            app.addHandlerFcns(@AcceptTypes.acceptBezier3, ...
                @DrawGeometry.drawBezier3,@ErrorHandler.bezier3Error);         
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            app.moveState();
            results = ExportWindow(app.UIFigure);
            if isempty(results); return; end
            [filename, pathname] = uiputfile({'*.m', 'MATLAB script (*.m)';}, 'Save As');
            figure(app.UIFigure);
            if isequal(filename, 0) || isequal(pathname, 0); return; end
            fullpath = fullfile(pathname, filename);
            try
                ExportScript(app.model,fullpath,results,app.funcDir);
            catch ME
                uialert(app.UIFigure,[ME.identifier newline ME.message],"Export error!");
            end
        end

        % Button pushed function: ParallelLineButton
        function ParallelLineButtonPushed(app, event)
            app.setTitle("Parallel Line");
            app.addHandlerFcns(@AcceptTypes.acceptParallelLine, ...
                @DrawGeometry.drawParallelLine, ...
                @ErrorHandler.parallelLineError);
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            app.clearAll();
            [filename, pathname] = uigetfile({'*.m', 'MATLAB script (*.m)';}, 'Select Script');
            figure(app.UIFigure);
            if isequal(filename, 0) || isequal(pathname, 0); return; end
            fullpath = fullfile(pathname,filename);
            try
                ImportScript(app,fullpath,app.model,app.funcDir);
                app.createSliderUIs();
            catch ME
                uialert(app.UIFigure,[ME.identifier newline ME.message],"Import error!");
                app.clearAll();
            end

        end

        % Callback function: FigureTools
        function FigureToolsClicked(app, event)
            app.switchToToolbarElem("FigureTools");
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.clearAll();
        end

        % Callback function: Distances
        function DistancesClicked(app, event)
            app.switchToToolbarElem("Distances");
        end

        % Button pushed function: AddDistanceButton
        function AddDistanceButtonPushed(app, event)
            app.setTitle("Distance");
            app.addHandlerFcns(@AcceptTypes.acceptDistance, ...
                @(o,data) app.drawDistance(o,data), ...
                @ErrorHandler.distanceError);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.deselect();
            app.removeTempFolder();
            delete(app);
        end

        % Button pushed function: MPolygonButton
        function MPolygonButtonPushed(app, event)
            app.setTitle("Moveable Polygon");
            app.addHandlerFcns( ...
                @(data) app.acceptMPolygon(data), ...
                @(o,data) app.drawMPolygon(o,data), ...
                @(o,data) app.sequenceError(o,data,@ErrorHandler.mpolygonError));
            app.EndSelectionButton.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'GeomatplotGui';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.Tools = uitoggletool(app.Toolbar);
            app.Tools.Tooltip = {'Geometric Tools'};
            app.Tools.ClickedCallback = createCallbackFcn(app, @ToolsClicked, true);
            app.Tools.Icon = fullfile(pathToMLAPP, 'resources', 'Geometry.png');
            app.Sliders = uitoggletool(app.Toolbar);
            app.Sliders.Tooltip = {'Sliders'};
            app.Sliders.ClickedCallback = createCallbackFcn(app, @SlidersClicked, true);
            app.Sliders.Icon = fullfile(pathToMLAPP, 'resources', 'Slider.png');
            app.Distances = uitoggletool(app.Toolbar);
            app.Distances.Tooltip = {'Distances'};
            app.Distances.ClickedCallback = createCallbackFcn(app, @DistancesClicked, true);
            app.Distances.Icon = fullfile(pathToMLAPP, 'resources', 'Distance.png');
            app.FigureTools = uitoggletool(app.Toolbar);
            app.FigureTools.Tooltip = {'I/O Tools'};
            app.FigureTools.ClickedCallback = createCallbackFcn(app, @FigureToolsClicked, true);
            app.FigureTools.Icon = fullfile(pathToMLAPP, 'resources', 'FigureTools.png');
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.ToolTabs = uitabgroup(app.UIFigure);
            app.ToolTabs.Visible = 'off';
            app.BasicTools = uitab(app.ToolTabs);
            app.BasicTools.Title = 'Basic Tools';
            app.BasicToolsGrid = uigridlayout(app.BasicTools);
            app.PointButton = uibutton(app.BasicToolsGrid, 'push');
            app.PointButton.ButtonPushedFcn = createCallbackFcn(app, @PointButtonPushed, true);
            app.PointButton.Icon = fullfile(pathToMLAPP, 'resources', 'Point.png');
            app.PointButton.Tooltip = {'Place a point.'};
            app.PointButton.Text = '';
            app.MoveButton = uibutton(app.BasicToolsGrid, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Icon = fullfile(pathToMLAPP, 'resources', 'Move.png');
            app.MoveButton.Tooltip = {'Move'};
            app.MoveButton.Text = '';
            app.MidpointButton = uibutton(app.BasicToolsGrid, 'push');
            app.MidpointButton.ButtonPushedFcn = createCallbackFcn(app, @MidpointButtonPushed, true);
            app.MidpointButton.Icon = fullfile(pathToMLAPP, 'resources', 'Midpoint.png');
            app.MidpointButton.Tooltip = {'Create Midpoint between two points.'};
            app.MidpointButton.Text = '';
            app.DPolygonButton = uibutton(app.BasicToolsGrid, 'push');
            app.DPolygonButton.ButtonPushedFcn = createCallbackFcn(app, @DPolygonButtonPushed, true);
            app.DPolygonButton.Icon = fullfile(pathToMLAPP, 'resources', 'DPolygon.png');
            app.DPolygonButton.Tooltip = {'Create a dependent polygon by selecting points.'};
            app.DPolygonButton.Text = '';
            app.IntersectButton = uibutton(app.BasicToolsGrid, 'push');
            app.IntersectButton.ButtonPushedFcn = createCallbackFcn(app, @IntersectButtonPushed, true);
            app.IntersectButton.Icon = fullfile(pathToMLAPP, 'resources', 'Intersect.png');
            app.IntersectButton.Tooltip = {'Creates an intersection between two selected figures.'};
            app.IntersectButton.Text = '';
            app.MirrorButton = uibutton(app.BasicToolsGrid, 'push');
            app.MirrorButton.ButtonPushedFcn = createCallbackFcn(app, @MirrorButtonPushed, true);
            app.MirrorButton.Icon = fullfile(pathToMLAPP, 'resources', 'Mirror.png');
            app.MirrorButton.Tooltip = {'Figure to be mirrored, then center.'};
            app.MirrorButton.Text = '';
            app.ClosestPointButton = uibutton(app.BasicToolsGrid, 'push');
            app.ClosestPointButton.ButtonPushedFcn = createCallbackFcn(app, @ClosestPointButtonPushed, true);
            app.ClosestPointButton.Icon = fullfile(pathToMLAPP, 'resources', 'ClosestPoint.png');
            app.ClosestPointButton.Tooltip = {'Closest point to a figure. '};
            app.ClosestPointButton.Text = '';
            app.CentroidPointButton = uibutton(app.BasicToolsGrid, 'push');
            app.CentroidPointButton.ButtonPushedFcn = createCallbackFcn(app, @CentroidPointButtonPushed, true);
            app.CentroidPointButton.Icon = fullfile(pathToMLAPP, 'resources', 'CentroidPoint.png');
            app.CentroidPointButton.Tooltip = {'Creates the centroid point of any number of points or polygons'};
            app.CentroidPointButton.Text = '';
            app.MirrorSegmentButton = uibutton(app.BasicToolsGrid, 'push');
            app.MirrorSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @MirrorSegmentButtonPushed, true);
            app.MirrorSegmentButton.Icon = fullfile(pathToMLAPP, 'resources', 'MirrorSegment.png');
            app.MirrorSegmentButton.Tooltip = {'Mirror a figure through a segment defined by points A and B.'};
            app.MirrorSegmentButton.Text = '';
            app.MPolygonButton = uibutton(app.BasicToolsGrid, 'push');
            app.MPolygonButton.ButtonPushedFcn = createCallbackFcn(app, @MPolygonButtonPushed, true);
            app.MPolygonButton.Icon = fullfile(pathToMLAPP, 'resources', 'MPolygon.png');
            app.MPolygonButton.Text = '';
            app.CircleTools = uitab(app.ToolTabs);
            app.CircleTools.Title = 'Circles';
            app.CircleToolsGrid = uigridlayout(app.CircleTools);
            app.Circle3Button = uibutton(app.CircleToolsGrid, 'push');
            app.Circle3Button.ButtonPushedFcn = createCallbackFcn(app, @Circle3ButtonPushed, true);
            app.Circle3Button.Icon = fullfile(pathToMLAPP, 'resources', 'Circle3.png');
            app.Circle3Button.Tooltip = {'Draws a circumscribed circle going through points A, B, and C.'};
            app.Circle3Button.Text = '';
            app.Circle2Button = uibutton(app.CircleToolsGrid, 'push');
            app.Circle2Button.ButtonPushedFcn = createCallbackFcn(app, @Circle2ButtonPushed, true);
            app.Circle2Button.Icon = fullfile(pathToMLAPP, 'resources', 'Circle2.png');
            app.Circle2Button.Tooltip = {'Draws a circle around point A extending to point B or with an r scalar value.'};
            app.Circle2Button.Text = '';
            app.CircularArcButton = uibutton(app.CircleToolsGrid, 'push');
            app.CircularArcButton.ButtonPushedFcn = createCallbackFcn(app, @CircularArcButtonPushed, true);
            app.CircularArcButton.Icon = fullfile(pathToMLAPP, 'resources', 'CircularArc.png');
            app.CircularArcButton.Tooltip = {'Draws a circular arc around O starting from B until it meets the OC line or with angle alpha in   anticlockwise direction.'};
            app.CircularArcButton.Text = '';
            app.LineTools = uitab(app.ToolTabs);
            app.LineTools.Title = 'Lines';
            app.LineToolsGrid = uigridlayout(app.LineTools);
            app.SegmentButton = uibutton(app.LineToolsGrid, 'push');
            app.SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentButtonPushed, true);
            app.SegmentButton.Icon = fullfile(pathToMLAPP, 'resources', 'Segment.png');
            app.SegmentButton.Tooltip = {'Draws a line segment between point A and point B.'};
            app.SegmentButton.Text = '';
            app.LineButton = uibutton(app.LineToolsGrid, 'push');
            app.LineButton.ButtonPushedFcn = createCallbackFcn(app, @LineButtonPushed, true);
            app.LineButton.Icon = fullfile(pathToMLAPP, 'resources', 'Line.png');
            app.LineButton.Tooltip = {'Draws a line between point A and point B extending past both points.'};
            app.LineButton.Text = '';
            app.PerpendicularBisectorButton = uibutton(app.LineToolsGrid, 'push');
            app.PerpendicularBisectorButton.ButtonPushedFcn = createCallbackFcn(app, @PerpendicularBisectorButtonPushed, true);
            app.PerpendicularBisectorButton.Icon = fullfile(pathToMLAPP, 'resources', 'PerpendicularBisector.png');
            app.PerpendicularBisectorButton.Tooltip = {'Draws the perpendicular bisector line seperating A and B points.'};
            app.PerpendicularBisectorButton.Text = '';
            app.AngleBisector3Button = uibutton(app.LineToolsGrid, 'push');
            app.AngleBisector3Button.ButtonPushedFcn = createCallbackFcn(app, @AngleBisector3ButtonPushed, true);
            app.AngleBisector3Button.Icon = fullfile(pathToMLAPP, 'resources', 'AngleBisector3.png');
            app.AngleBisector3Button.Tooltip = {'Draws the angular bisector line through B and between A and C.'};
            app.AngleBisector3Button.Text = '';
            app.AngleBisector4Button = uibutton(app.LineToolsGrid, 'push');
            app.AngleBisector4Button.ButtonPushedFcn = createCallbackFcn(app, @AngleBisector4ButtonPushed, true);
            app.AngleBisector4Button.Icon = fullfile(pathToMLAPP, 'resources', 'AngleBisector4.png');
            app.AngleBisector4Button.Tooltip = {'Draws the two angular bisectors of the lines AB and CD.'};
            app.AngleBisector4Button.Text = '';
            app.PerpendicularLine3Button = uibutton(app.LineToolsGrid, 'push');
            app.PerpendicularLine3Button.ButtonPushedFcn = createCallbackFcn(app, @PerpendicularLine3ButtonPushed, true);
            app.PerpendicularLine3Button.Icon = fullfile(pathToMLAPP, 'resources', 'PerpendicularLine3.png');
            app.PerpendicularLine3Button.Tooltip = {'Draws a perpendicular line from A to BC.'};
            app.PerpendicularLine3Button.Text = '';
            app.PerpendicularLine2Button = uibutton(app.LineToolsGrid, 'push');
            app.PerpendicularLine2Button.ButtonPushedFcn = createCallbackFcn(app, @PerpendicularLine2ButtonPushed, true);
            app.PerpendicularLine2Button.Icon = fullfile(pathToMLAPP, 'resources', 'PerpendicularLine2.png');
            app.PerpendicularLine2Button.Tooltip = {'Draws a perpendicular line from A to AB.'};
            app.PerpendicularLine2Button.Text = '';
            app.RayButton = uibutton(app.LineToolsGrid, 'push');
            app.RayButton.ButtonPushedFcn = createCallbackFcn(app, @RayButtonPushed, true);
            app.RayButton.Icon = fullfile(pathToMLAPP, 'resources', 'Ray.png');
            app.RayButton.Tooltip = {'Draws a ray between point A and point B extending past point B.'};
            app.RayButton.Text = '';
            app.VectorButton = uibutton(app.LineToolsGrid, 'push');
            app.VectorButton.ButtonPushedFcn = createCallbackFcn(app, @VectorButtonPushed, true);
            app.VectorButton.Icon = fullfile(pathToMLAPP, 'resources', 'Vector.png');
            app.VectorButton.Tooltip = {'Draws a vector from A to B.'};
            app.VectorButton.Text = '';
            app.SegmentSequenceStripButton = uibutton(app.LineToolsGrid, 'push');
            app.SegmentSequenceStripButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentSequenceStripButtonPushed, true);
            app.SegmentSequenceStripButton.Icon = fullfile(pathToMLAPP, 'resources', 'SegmentSequenceStrip.png');
            app.SegmentSequenceStripButton.Tooltip = {'Draws line segments between point pairs.'};
            app.SegmentSequenceStripButton.Text = '';
            app.SegmentSequenceLinesButton = uibutton(app.LineToolsGrid, 'push');
            app.SegmentSequenceLinesButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentSequenceLinesButtonPushed, true);
            app.SegmentSequenceLinesButton.Icon = fullfile(pathToMLAPP, 'resources', 'SegmentSequenceLines.png');
            app.SegmentSequenceLinesButton.Tooltip = {'Draws line segments between point pairs, breaks into lines.'};
            app.SegmentSequenceLinesButton.Text = '';
            app.SegmentSequenceTrianglesButton = uibutton(app.LineToolsGrid, 'push');
            app.SegmentSequenceTrianglesButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentSequenceTrianglesButtonPushed, true);
            app.SegmentSequenceTrianglesButton.Icon = fullfile(pathToMLAPP, 'resources', 'SegmentSequenceTriangles.png');
            app.SegmentSequenceTrianglesButton.Tooltip = {'Draws line segments between point pairs, breaks into triangles.'};
            app.SegmentSequenceTrianglesButton.Text = '';
            app.ParallelLineButton = uibutton(app.LineToolsGrid, 'push');
            app.ParallelLineButton.ButtonPushedFcn = createCallbackFcn(app, @ParallelLineButtonPushed, true);
            app.ParallelLineButton.Icon = fullfile(pathToMLAPP, 'resources', 'ParallelLine.png');
            app.ParallelLineButton.Tooltip = {'Draws a parallel line through A to BC or a translated parallel line to BC at signed distance d.'};
            app.ParallelLineButton.Text = '';
            app.CurveTools = uitab(app.ToolTabs);
            app.CurveTools.Title = 'Curves';
            app.GridLayout = uigridlayout(app.CurveTools);
            app.Bezier2Button = uibutton(app.GridLayout, 'push');
            app.Bezier2Button.ButtonPushedFcn = createCallbackFcn(app, @Bezier2ButtonPushed, true);
            app.Bezier2Button.Icon = fullfile(pathToMLAPP, 'resources', 'Bezier2.png');
            app.Bezier2Button.Tooltip = {'Create a quadratic bezier curve with 3 control points.'};
            app.Bezier2Button.Text = '';
            app.Bezier3Button = uibutton(app.GridLayout, 'push');
            app.Bezier3Button.ButtonPushedFcn = createCallbackFcn(app, @Bezier3ButtonPushed, true);
            app.Bezier3Button.Icon = fullfile(pathToMLAPP, 'resources', 'Bezier3.png');
            app.Bezier3Button.Tooltip = {'Create a cubic bezier curve with 4 control points.'};
            app.Bezier3Button.Text = '';
            app.EndSelectionButton = uibutton(app.UIFigure, 'push');
            app.EndSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @EndSelectionButtonPushed, true);
            app.EndSelectionButton.Text = {'End'; 'Selection'};
            app.SliderPanel = uipanel(app.UIFigure);
            app.SliderPanel.Visible = 'off';
            app.SliderLayout = uigridlayout(app.SliderPanel);
            app.AddSliderButton = uibutton(app.SliderLayout, 'push');
            app.AddSliderButton.ButtonPushedFcn = createCallbackFcn(app, @AddSliderButtonPushed, true);
            app.AddSliderButton.Text = 'Add Slider';
            app.SliderList = uigridlayout(app.SliderLayout);
            app.FigureToolsPanel = uipanel(app.UIFigure);
            app.FigureToolsPanel.Visible = 'off';
            app.FigureToolsLayout = uigridlayout(app.FigureToolsPanel);
            app.ImportButton = uibutton(app.FigureToolsLayout, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Tooltip = {'Import geomatplot script to gui.'};
            app.ImportButton.Text = 'Import';
            app.ClearAllButton = uibutton(app.FigureToolsLayout, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Tooltip = {'Clear Geomatplot.'};
            app.ClearAllButton.Text = 'Clear All';
            app.ExportButton = uibutton(app.FigureToolsLayout, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Tooltip = {'Export the current geomatplot state to code.'};
            app.ExportButton.Text = 'Export';
            app.DistancesPanel = uipanel(app.UIFigure);
            app.DistancesPanel.Visible = 'off';
            app.DistanceLayout = uigridlayout(app.DistancesPanel);
            app.AddDistanceButton = uibutton(app.DistanceLayout, 'push');
            app.AddDistanceButton.ButtonPushedFcn = createCallbackFcn(app, @AddDistanceButtonPushed, true);
            app.AddDistanceButton.Text = 'Add Distance';
            app.DistanceList = uigridlayout(app.DistanceLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GeomatplotGUI

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end